classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ModelModePanel                 matlab.ui.container.Panel
        Switch                         matlab.ui.control.Switch
        SamNumberSpinner               matlab.ui.control.Spinner
        SamNumberSpinnerLabel          matlab.ui.control.Label
        MethodsPanel                   matlab.ui.container.Panel
        Methods                        matlab.ui.control.DropDown
        WirelessSensingSimulatorLabel  matlab.ui.control.Label
        CaculateButton                 matlab.ui.control.Button
        CalculationPanel               matlab.ui.container.Panel
        ToFEditField_2                 matlab.ui.control.NumericEditField
        ToFEditField_2Label            matlab.ui.control.Label
        AoAEditField_2                 matlab.ui.control.NumericEditField
        AoAEditField_2Label            matlab.ui.control.Label
        GroundTruthPanel               matlab.ui.container.Panel
        ToFEditField                   matlab.ui.control.NumericEditField
        ToFEditFieldLabel              matlab.ui.control.Label
        AoAEditField                   matlab.ui.control.NumericEditField
        AoAEditFieldLabel              matlab.ui.control.Label
        ParametersSettingPanel         matlab.ui.container.Panel
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        zLabel                         matlab.ui.control.Label
        yLabel                         matlab.ui.control.Label
        xLabel                         matlab.ui.control.Label
        RxRotEditField                 matlab.ui.control.NumericEditField
        RxRotEditFieldLabel            matlab.ui.control.Label
        EditField_7                    matlab.ui.control.NumericEditField
        TxLocEditField                 matlab.ui.control.NumericEditField
        TxLocEditFieldLabel            matlab.ui.control.Label
        EditField_6                    matlab.ui.control.NumericEditField
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        RxLocEditField                 matlab.ui.control.NumericEditField
        RxLocEditFieldLabel            matlab.ui.control.Label
        BandWidthDropDown              matlab.ui.control.DropDown
        BandWidthDropDownLabel         matlab.ui.control.Label
        SubcarrierDropDown             matlab.ui.control.DropDown
        SubcarrierDropDownLabel        matlab.ui.control.Label
        RxNumberSpinner                matlab.ui.control.Spinner
        RxNumberSpinnerLabel           matlab.ui.control.Label
        TxNumberSpinner                matlab.ui.control.Spinner
        NumTxLabel                     matlab.ui.control.Label
        DisplayButton                  matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.Switch.Value = 'Still';
           app.SamNumberSpinner.Value = 1;
           app.SamNumberSpinner.Enable = 'off';
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
      
            dataFile = './data/dataset.mat';
            
            %% 0. Parameter Setup and Initlization
            % Load the indoor Model files.
            indoorModel = "office.stl";
            
            % Set the wireless parameters.
            fc = 5.8e9;
            lambda = physconst("lightspeed")/fc;
            numSubcarrier = str2double(app.SubcarrierDropDown.Value);
            
            % Set the device parameters.
            SamNumber = app.SamNumberSpinner.Value;
            numTx = app.TxNumberSpinner.Value;
            numRx = app.RxNumberSpinner.Value;
            txArraySize = [numTx 1];    
            rxArraySize = [numRx/2 numRx/2];

            txArray = arrayConfig("Size", txArraySize, "ElementSpacing", lambda/2);
            rxArray = arrayConfig("Size", rxArraySize, "ElementSpacing", lambda/2);
            
            % Configure the 802.11ac waveform.
            vhtConfig = wlanVHTConfig;
            vhtConfig.ChannelBandwidth = ['CBW',num2str(app.BandWidthDropDown.Value)];
            vhtConfig.MCS = 0;
            vhtConfig.NumTransmitAntennas = 1;
            vhtConfig.NumSpaceTimeStreams = vhtConfig.NumTransmitAntennas;
            vhtConfig.APEPLength = 1024; 
            vhtConfig.ChannelCoding = 'LDPC';
            vhtConfig.Beamforming = false;
            sampleRate = wlanSampleRate(vhtConfig);
            

            numPkt = 3;
            %% 1. Simulate and Get CSI Data
            
            % Randomly set the poses of the AP and a group of IoT devices.
            locAP = [app.RxLocEditField.Value;app.EditField.Value;app.EditField_2.Value]; %[0~5, 0~8, 0~3.2]
            rotAP = [app.RxRotEditField.Value;app.EditField_7.Value]; % [0~360,-90~90]
            ap = rxsite('cartesian', 'Antenna', rxArray, 'AntennaPosition', locAP, 'AntennaAngle', rotAP, 'ReceiverSensitivity', -80);
            if strcmp(app.Switch.Value,'Still')
                locIoT = [app.TxLocEditField.Value;app.EditField_5.Value;app.EditField_6.Value];
            else
                locIoT = [app.TxLocEditField.Value;app.EditField_5.Value;app.EditField_6.Value];
                locIoT = repmat(locIoT, 1, SamNumber);
                interval = 0.05;
                increment = 0:interval:interval*(SamNumber-1);
                locIoT(1, :) = locIoT(1, :) + increment;
                iot = txsite('cartesian', 'Antenna', txArray, 'AntennaPosition', locIoT, 'TransmitterFrequency', fc, 'TransmitterPower', 30);
                chanModel = propagationModel('raytracing', 'CoordinateSystem', 'cartesian', 'MaxNumReflection', 0, "SurfaceMaterial", "concrete");
                noise = 1000;
                raytemp = raytrace(iot, ap, chanModel, 'Map', indoorModel);
                csitemp = zeros([SamNumber, numSubcarrier, numTx*numRx], 'double');
                for n = 1:SamNumber
                    curRays = raytemp{n, 1};
                    rtChanIoT = comm.RayTracingChannel(curRays, iot(n), ap);
                    rtChanIoT.SampleRate = sampleRate;
                    rtChanIoT.ReceiverVirtualVelocity = zeros(3, 1);
                    rtChanIoT.NormalizeChannelOutputs = false;
                    [~, ~, chanEst] = acSim(rtChanIoT, vhtConfig, numPkt, noise);
                    csitemp(n, :, :) = squeeze(chanEst);
                end
            end
            noise = 30; % dB
            iot = txsite('cartesian', 'Antenna', txArray, 'AntennaPosition', locIoT, 'TransmitterFrequency', fc, 'TransmitterPower', 30);
            chanModel = propagationModel('raytracing', 'CoordinateSystem', 'cartesian', 'MaxNumReflection', 2, "SurfaceMaterial", "concrete");
            viewer = siteviewer("SceneModel", indoorModel, "Transparency", 0.25);
            raytrace(iot, ap, chanModel)
            rayIoT = raytrace(iot, ap, chanModel, 'Map', indoorModel);
            % If any empty ray exists, reset the poses of the AP.
            if sum(cellfun(@isempty, rayIoT), 'all')
                disp("-  Try to reset AP and IoT.");
            end

            csiIoT = zeros([SamNumber, numSubcarrier, numTx*numRx], 'double');
            aoaIoT = cell([SamNumber, 1]);
            tofIoT = cell([SamNumber, 1]);
            
            for n = 1:SamNumber
                curRays = rayIoT{n, 1};
                rtChanIoT = comm.RayTracingChannel(curRays, iot(n), ap);
                rtChanIoT.SampleRate = sampleRate;
                rtChanIoT.ReceiverVirtualVelocity = zeros(3, 1);
                rtChanIoT.NormalizeChannelOutputs = false;
                aoaIoT{n, 1} = [curRays.AngleOfArrival];
                tofIoT{n, 1} = [curRays.PropagationDelay];
                [~, ~, chanEst] = acSim(rtChanIoT, vhtConfig, numPkt, noise);
                csiIoT(n, :, :) = squeeze(chanEst);
            end
            dataCell = {csiIoT};
            gtCell = {aoaIoT, tofIoT};
            
            save(dataFile, 'dataCell', 'gtCell');
            phiList = [];
            thetaList = [];
            tofList = [];
            for m = 1:SamNumber
                aoaData = aoaIoT{m,1};
                aoa_phi = [phiList,aoaData(1,1)];
                aoa_theta = [thetaList,aoaData(2,1)];
                tofData = tofIoT{m,1};
                tof = [tofList,tofData(1,1)];
            end

            phi = mean(aoa_phi);
            theta = mean(aoa_theta);
            tof = mean(tof);
            app.AoAEditField.Value = phi;

            if strcmp(app.Switch.Value,'Moving')
                [~,~,~] = naive_spectrum(csitemp,4,0,app.UIAxes);
            end
            app.ToFEditField.Value = tof;
                
        end

        % Button pushed function: CaculateButton
        function CaculateButtonPushed(app, event)
            dataFile = './data/dataset.mat';
            load(dataFile)
            csi = dataCell{1,1};
            bw = str2double(app.BandWidthDropDown.Value)*1000000;
            fc = 5.8e9;
            lambda = physconst("lightspeed")/fc;
            numSubcarrier = 56;
            fc_list=fc-1e7:200/numSubcarrier*1e5:fc+1e7;
            fc_list(numSubcarrier/2)=[];
            lambda_list=physconst("lightspeed")./fc_list;
           
            
            % MUSIC_aoa
            if strcmp(app.Methods.Value, 'Naive')
                tof = naive_tof(csi,bw);
                app.ToFEditField_2.Value = tof;

                locAP = [app.RxLocEditField.Value;app.EditField.Value;app.EditField_2.Value];
                locAP = repmat(locAP, 1, app.RxNumberSpinner.Value);
                interval = lambda/2;
                locAP(1, 2) = locAP(1, 2) + interval;
                locAP(2, 3) = locAP(2, 3) + interval;
                locAP(1, 4) = locAP(1, 4) + interval;
                locAP(2, 4) = locAP(2, 4) + interval;
                phi = naive_aoa(csi,locAP,[],lambda_list);

                app.AoAEditField_2.Value = phi;
            elseif strcmp(app.Methods.Value, 'Grid Search')
                phi = grid_search_aoa(csi, lambda, lambda_list);
                app.AoAEditField_2.Value = phi;
                tof = grid_search_tof(csi, fc_list);
                app.ToFEditField_2.Value = tof;
            elseif strcmp(app.Methods.Value, 'mD-Track')
                
            end

            if strcmp(app.Switch.Value,'Moving')
                [~,~,~] = naive_spectrum(dataCell{1,1},4,0,app.UIAxes2);
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Still')
                app.SamNumberSpinner.Value = 1;
                app.SamNumberSpinner.Enable = 'off';
            else
                app.SamNumberSpinner.Enable = 'on';
                app.SamNumberSpinner.Value = 20;
            end
        end

        % Value changed function: BandWidthDropDown
        function BandWidthDropDownValueChanged(app, event)
            value = app.BandWidthDropDown.Value;
            if strcmp(value,'20')
                app.SubcarrierDropDown.Value = '56';
            elseif strcmp(value,'40')
                app.SubcarrierDropDown.Value = '114';
            elseif strcmp(value,'80')
                app.SubcarrierDropDown.Value = '242';
            else
                app.SubcarrierDropDown.Value = '484';
            end
        end

        % Value changed function: SubcarrierDropDown
        function SubcarrierDropDownValueChanged(app, event)
            value = app.SubcarrierDropDown.Value;
                        
            if strcmp(value,'56')
                app.BandWidthDropDown.Value = '20';
            elseif strcmp(value,'114')
                app.BandWidthDropDown.Value = '40';
            elseif strcmp(value,'242')
                app.BandWidthDropDown.Value = '80';
            else
                app.BandWidthDropDown.Value = '160';
            end
        end

        % Value changed function: Methods
        function MethodsValueChanged(app, event)
            value = app.Methods.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Calculated Phase Shift Spectrum')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Real Phase shift spectrum')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DisplayButton = uibutton(app.UIFigure, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.ParametersSettingPanel = uipanel(app.UIFigure);
            app.ParametersSettingPanel.Title = 'Parameters Setting';
            app.NumTxLabel = uilabel(app.ParametersSettingPanel);
            app.NumTxLabel.Enable = 'off';
            app.NumTxLabel.Text = 'TxNumber';
            app.TxNumberSpinner = uispinner(app.ParametersSettingPanel);
            app.TxNumberSpinner.Limits = [1 Inf];
            app.TxNumberSpinner.Enable = 'off';
            app.TxNumberSpinner.Value = 1;
            app.RxNumberSpinnerLabel = uilabel(app.ParametersSettingPanel);
            app.RxNumberSpinnerLabel.Enable = 'off';
            app.RxNumberSpinnerLabel.Text = 'RxNumber';
            app.RxNumberSpinner = uispinner(app.ParametersSettingPanel);
            app.RxNumberSpinner.Limits = [4 Inf];
            app.RxNumberSpinner.Enable = 'off';
            app.RxNumberSpinner.Value = 4;
            app.SubcarrierDropDownLabel = uilabel(app.ParametersSettingPanel);
            app.SubcarrierDropDownLabel.Text = 'Subcarrier';
            app.SubcarrierDropDown = uidropdown(app.ParametersSettingPanel);
            app.SubcarrierDropDown.Items = {'56', '114', '242', '484'};
            app.SubcarrierDropDown.ValueChangedFcn = createCallbackFcn(app, @SubcarrierDropDownValueChanged, true);
            app.SubcarrierDropDown.Placeholder = '56';
            app.SubcarrierDropDown.Value = '56';
            app.BandWidthDropDownLabel = uilabel(app.ParametersSettingPanel);
            app.BandWidthDropDownLabel.Text = 'BandWidth';
            app.BandWidthDropDown = uidropdown(app.ParametersSettingPanel);
            app.BandWidthDropDown.Items = {'20', '40', '80', '160'};
            app.BandWidthDropDown.ValueChangedFcn = createCallbackFcn(app, @BandWidthDropDownValueChanged, true);
            app.BandWidthDropDown.Placeholder = '20';
            app.BandWidthDropDown.Value = '20';
            app.RxLocEditFieldLabel = uilabel(app.ParametersSettingPanel);
            app.RxLocEditFieldLabel.Text = 'RxLoc';
            app.RxLocEditField = uieditfield(app.ParametersSettingPanel, 'numeric');
            app.RxLocEditField.Value = 2;
            app.EditField = uieditfield(app.ParametersSettingPanel, 'numeric');
            app.EditField.Value = 3;
            app.EditField_2 = uieditfield(app.ParametersSettingPanel, 'numeric');
            app.EditField_2.Value = 1.8;
            app.EditField_5 = uieditfield(app.ParametersSettingPanel, 'numeric');
            app.EditField_5.Value = 4;
            app.EditField_6 = uieditfield(app.ParametersSettingPanel, 'numeric');
            app.EditField_6.Value = 1.8;
            app.TxLocEditFieldLabel = uilabel(app.ParametersSettingPanel);
            app.TxLocEditFieldLabel.Text = 'TxLoc';
            app.TxLocEditField = uieditfield(app.ParametersSettingPanel, 'numeric');
            app.TxLocEditField.Value = 3;
            app.EditField_7 = uieditfield(app.ParametersSettingPanel, 'numeric');
            app.EditField_7.Enable = 'off';
            app.EditField_7.Value = 90;
            app.RxRotEditFieldLabel = uilabel(app.ParametersSettingPanel);
            app.RxRotEditFieldLabel.Text = 'RxRot';
            app.RxRotEditField = uieditfield(app.ParametersSettingPanel, 'numeric');
            app.RxRotEditField.Enable = 'off';
            app.RxRotEditField.Value = 180;
            app.xLabel = uilabel(app.ParametersSettingPanel);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.ParametersSettingPanel);
            app.yLabel.Text = 'y';
            app.zLabel = uilabel(app.ParametersSettingPanel);
            app.zLabel.Text = 'z';
            app.Label = uilabel(app.ParametersSettingPanel);
            app.Label.Text = 'φ';
            app.Label_2 = uilabel(app.ParametersSettingPanel);
            app.Label_2.Text = 'θ';
            app.GroundTruthPanel = uipanel(app.UIFigure);
            app.GroundTruthPanel.Title = 'Ground Truth';
            app.AoAEditFieldLabel = uilabel(app.GroundTruthPanel);
            app.AoAEditFieldLabel.Text = 'AoA';
            app.AoAEditField = uieditfield(app.GroundTruthPanel, 'numeric');
            app.ToFEditFieldLabel = uilabel(app.GroundTruthPanel);
            app.ToFEditFieldLabel.Text = 'ToF';
            app.ToFEditField = uieditfield(app.GroundTruthPanel, 'numeric');
            app.CalculationPanel = uipanel(app.UIFigure);
            app.CalculationPanel.Title = 'Calculation';
            app.AoAEditField_2Label = uilabel(app.CalculationPanel);
            app.AoAEditField_2Label.Text = 'AoA';
            app.AoAEditField_2 = uieditfield(app.CalculationPanel, 'numeric');
            app.ToFEditField_2Label = uilabel(app.CalculationPanel);
            app.ToFEditField_2Label.Text = 'ToF';
            app.ToFEditField_2 = uieditfield(app.CalculationPanel, 'numeric');
            app.CaculateButton = uibutton(app.UIFigure, 'push');
            app.CaculateButton.ButtonPushedFcn = createCallbackFcn(app, @CaculateButtonPushed, true);
            app.CaculateButton.Text = 'Caculate';
            app.WirelessSensingSimulatorLabel = uilabel(app.UIFigure);
            app.WirelessSensingSimulatorLabel.Text = 'Wireless Sensing Simulator';
            app.MethodsPanel = uipanel(app.UIFigure);
            app.MethodsPanel.Title = 'Methods';
            app.Methods = uidropdown(app.MethodsPanel);
            app.Methods.Items = {'Naive', 'Grid Search', 'mD-Track', 'Others'};
            app.Methods.ValueChangedFcn = createCallbackFcn(app, @MethodsValueChanged, true);
            app.Methods.Value = 'Naive';
            app.ModelModePanel = uipanel(app.UIFigure);
            app.ModelModePanel.Title = 'Model Mode';
            app.SamNumberSpinnerLabel = uilabel(app.ModelModePanel);
            app.SamNumberSpinnerLabel.Text = 'SamNumber';
            app.SamNumberSpinner = uispinner(app.ModelModePanel);
            app.SamNumberSpinner.Limits = [1 Inf];
            app.SamNumberSpinner.Value = 1;
            app.Switch = uiswitch(app.ModelModePanel, 'slider');
            app.Switch.Items = {'Still', 'Moving'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Still';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end